classdef DigitizeFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadFigure_pushbutton      matlab.ui.control.Button
        PlotFigure_pushbutton      matlab.ui.control.Button
        maxYLabel                  matlab.ui.control.Label
        Max_Y_edit                 matlab.ui.control.NumericEditField
        minYLabel                  matlab.ui.control.Label
        Min_Y_edit                 matlab.ui.control.NumericEditField
        PickMin_Y_pushbutton       matlab.ui.control.Button
        PickMax_Y_pushbutton       matlab.ui.control.Button
        PickMin_X_pushbutton       matlab.ui.control.Button
        PickMax_X_pushbutton       matlab.ui.control.Button
        maxXLabel                  matlab.ui.control.Label
        Max_X_edit                 matlab.ui.control.NumericEditField
        minXLabel                  matlab.ui.control.Label
        Min_X_edit                 matlab.ui.control.NumericEditField
        scaleofXDropDownLabel      matlab.ui.control.Label
        ScaleofX_dropdown          matlab.ui.control.DropDown
        scaleofYDropDownLabel      matlab.ui.control.Label
        ScaleofY_dropdown          matlab.ui.control.DropDown
        DigitizeSeries_pushbutton  matlab.ui.control.Button
        datatagEditFieldLabel      matlab.ui.control.Label
        SeriesTag_edit             matlab.ui.control.NumericEditField
        SaveSeries_pushbutton      matlab.ui.control.Button
        PlotSeries_pushbutton      matlab.ui.control.Button
        pointsizeLabel             matlab.ui.control.Label
        PlotPntSize_edit           matlab.ui.control.NumericEditField
        gridon_checkbox            matlab.ui.control.CheckBox
        axisequal_checkbox         matlab.ui.control.CheckBox
    end


    properties (Access = private)
        LoadedFigure; % the figure that is loaded
        SeriesDataRaw  = [];
        SeriesDataScld = [];
        myDigiFig;
        myOutpFig;
        Min_X_pixelVal = [];
        Max_X_pixelVal = [];
        Min_Y_pixelVal = [];
        Max_Y_pixelVal = [];
        ColorList = round(rand(100,3).*255);
    end
    
    methods (Access = private)
        %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx
        function ScaledSeries = ScaleSeriesData(app)
            minXpix = app.Min_X_pixelVal;               maxXpix = app.Max_X_pixelVal;        
            minYpix = app.Min_Y_pixelVal;               maxYpix = app.Max_Y_pixelVal;
            minXent = app.Min_X_edit.Value;             maxXent = app.Max_X_edit.Value;
            minYent = app.Min_Y_edit.Value;             maxYent = app.Max_Y_edit.Value;

            Yscale  = app.ScaleofX_dropdown.Value;
            Xscale  = app.ScaleofY_dropdown.Value;

            if (size(app.SeriesDataRaw,1) ==0);     ScaledSeries = [];       return;         end
        
            ScaledSeries = zeros(size(app.SeriesDataRaw));
            %................%................
   
            if strcmp(Xscale,'linear') == 1
               deltaXFact    = (maxXent -minXent)/(maxXpix -minXpix);
            else
               maxXent      = log10(maxXent);
               minXent      = log10(minXent);
               deltaXFact    = (maxXent -minXent)/(maxXpix -minXpix);
            end
            %................
            if strcmp(Yscale,'linear') == 1
                deltaYFact   = (maxYent -minYent)/(maxYpix -minYpix);
            else
                minYent      = log10(minYent);
                maxYent      = log10(maxYent);
                deltaYFact   = (maxYent -minYent)/(maxYpix -minYpix);
            end

            %.................%................
            for i = 1:size(app.SeriesDataRaw,1)
                if strcmp(Xscale,'linear') == 1
                    ScaledSeries(i,1)  =  minXent +deltaXFact*(app.SeriesDataRaw(i,1)-minXpix);
                else
                    ScaledSeries(i,1)  =  10^(minXent +deltaXFact*(app.SeriesDataRaw(i,1)-minXpix));
                end
                
                if strcmp(Yscale,'linear') == 1
                    ScaledSeries(i,2)  =  minYent +deltaYFact*(app.SeriesDataRaw(i,2)-minYpix);
                else
                    ScaledSeries(i,2)  =  10^(minYent +deltaYFact*(app.SeriesDataRaw(i,2)-minYpix));
                end
                
                ScaledSeries(i,3)  =  app.SeriesDataRaw(i,3);
            end
        end
        %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx
        function PlotScaledSeries(app)
           
            app.myOutpFig  = figure(2);      clf;       hold on;
            set(gca,'color',[0.4 0.4 0.4]);
            if (app.gridon_checkbox.Value == 1),        grid on;        end
            if (app.axisequal_checkbox.Value == 1),     axis equal;     end
            xlabel('x-axis');
            ylabel('y-axis');
            title('digitized data set');
            scatter(app.SeriesDataScld(:,1),app.SeriesDataScld(:,2),app.PlotPntSize_edit.Value,'filled');
        end
        %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx
    end
    

    methods (Access = private)

        % Button pushed function: LoadFigure_pushbutton
        function LoadFigure_pushbuttonButtonPushed(app, event)
            [DataName,DataPath]  = uigetfile({'*.png;*.jpg;*.jpeg'},'import image file');
            if     (DataName ~=0),      CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            
            app.LoadedFigure = imread(CompletePath);
            msgbox('Data import completed');
        end

        % Button pushed function: PlotFigure_pushbutton
        function PlotFigure_pushbuttonButtonPushed(app, event)
            if (~isempty(app.LoadedFigure))
                app.myDigiFig = figure(1);
                imagesc(app.LoadedFigure);
                xlabel('x-axis');
                ylabel('y-axis');
                title('imported figure, when digitizing: left-mouse = add point; right-mouse = remove point; space-bar = finish');
 
                app.SeriesDataRaw  = [];
                app.SeriesDataScld = [];
                app.Min_X_pixelVal = [];
                app.Max_X_pixelVal = [];
                app.Min_Y_pixelVal = [];
                app.Max_Y_pixelVal = [];
            end 
        end

        % Button pushed function: PickMin_Y_pushbutton
        function PickMin_Y_pushbuttonButtonPushed(app, event)
            figure(app.myDigiFig);   
            [x,y]              = ginput(1);        
            app.Min_Y_pixelVal = y;   
        end

        % Button pushed function: PickMax_Y_pushbutton
        function PickMax_Y_pushbuttonButtonPushed(app, event)
            figure(app.myDigiFig);   
            [x,y]              = ginput(1);        
            app.Max_Y_pixelVal = y;   
        end

        % Button pushed function: PickMin_X_pushbutton
        function PickMin_X_pushbuttonButtonPushed(app, event)
            figure(app.myDigiFig);   
            [x,y]              = ginput(1);        
            app.Min_X_pixelVal = x;    
        end

        % Button pushed function: PickMax_X_pushbutton
        function PickMax_X_pushbuttonButtonPushed(app, event)
            figure(app.myDigiFig);      
            [x,y]              = ginput(1);        
            app.Max_X_pixelVal = x;   
        end

        % Button pushed function: DigitizeSeries_pushbutton
        function DigitizeSeries_pushbuttonButtonPushed(app, event)

            if (isempty(app.Min_X_pixelVal)||isempty(app.Max_X_pixelVal)||isempty(app.Min_Y_pixelVal)||isempty(app.Max_Y_pixelVal))
                errordlg('At least on of the max/min values was not picked.');
                return;
            end
            %......................
            SeriesTag      = app.SeriesTag_edit.Value;
            EnteredFeatOld = app.SeriesDataRaw;
            EnteredFeatNew = [];
            
            counter        = 0;   
            TempHandlePt   = [];         
            %......................
            figure(app.myDigiFig);    
            hold on;
            but = 1;
            %......................%......................
            while but ~= 32
                [xi,yi,but] = ginput(1);
                
                if (but == 3) %remove a point
                   
                    if (size(EnteredFeatNew,1) == 1),           EnteredFeatNew = [];                          counter = 0;            delete(TempHandlePt(1,1));      TempHandlePt = [];                                          end
                    if (size(EnteredFeatNew,1) >= 2),           EnteredFeatNew = EnteredFeatNew(1:end-1,:);   counter = counter -1;   delete(TempHandlePt(end,1));    TempHandlePt = TempHandlePt(1:end-1,1);                     end

                elseif (but == 32)
                    %nothing to be done here but don't add the point!    
                else
                    EnteredFeatNew  = [EnteredFeatNew;xi, yi, SeriesTag];                                                  
                    TempHandle      = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',6);
                    TempHandlePt    = [TempHandlePt; TempHandle];
                    counter         = counter  +1;    
                end
            end
            %......................%......................
            app.SeriesDataRaw  = [EnteredFeatOld; EnteredFeatNew];
            app.SeriesDataScld = ScaleSeriesData(app);
            PlotScaledSeries(app);
            
        end

        % Button pushed function: PlotSeries_pushbutton
        function PlotSeries_pushbuttonButtonPushed(app, event)
            PlotScaledSeries(app);  
        end

        % Button pushed function: SaveSeries_pushbutton
        function SaveSeries_pushbuttonButtonPushed(app, event)
             [DataName,DataPath]  = uiputfile('*.txt','select output (*.txt) file');
            if     (DataName ~=0),      CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
   size(app.SeriesDataScld,1) 
            fid = fopen(CompletePath ,'w');
            if (fid == -1), errordlg('The entered filename has not been found.');    return;   end
            for i = 1 : size(app.SeriesDataScld,1)  
                fprintf(fid,'%10.4e    %10.4e    %10.4e\n',app.SeriesDataScld(i,1),app.SeriesDataScld(i,2),app.SeriesDataScld(i,3));
            end
            msgbox('Data export completed');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadFigure_pushbutton = uibutton(app.UIFigure, 'push');
            app.LoadFigure_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @LoadFigure_pushbuttonButtonPushed, true);
            app.LoadFigure_pushbutton.Text = 'Load figure';
            app.PlotFigure_pushbutton = uibutton(app.UIFigure, 'push');
            app.PlotFigure_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @PlotFigure_pushbuttonButtonPushed, true);
            app.PlotFigure_pushbutton.Text = 'Plot figure';
            app.maxYLabel = uilabel(app.UIFigure);
            app.maxYLabel.Text = 'max. Y';
            app.Max_Y_edit = uieditfield(app.UIFigure, 'numeric');
            app.Max_Y_edit.Value = 10;
            app.minYLabel = uilabel(app.UIFigure);
            app.minYLabel.Text = 'min. Y';
            app.Min_Y_edit = uieditfield(app.UIFigure, 'numeric');
            app.PickMin_Y_pushbutton = uibutton(app.UIFigure, 'push');
            app.PickMin_Y_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @PickMin_Y_pushbuttonButtonPushed, true);
            app.PickMin_Y_pushbutton.Text = 'Pick min. Y';
            app.PickMax_Y_pushbutton = uibutton(app.UIFigure, 'push');
            app.PickMax_Y_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @PickMax_Y_pushbuttonButtonPushed, true);
            app.PickMax_Y_pushbutton.Text = 'Pick max. Y';
            app.PickMin_X_pushbutton = uibutton(app.UIFigure, 'push');
            app.PickMin_X_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @PickMin_X_pushbuttonButtonPushed, true);
            app.PickMin_X_pushbutton.Text = 'Pick min. X';
            app.PickMax_X_pushbutton = uibutton(app.UIFigure, 'push');
            app.PickMax_X_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @PickMax_X_pushbuttonButtonPushed, true);
            app.PickMax_X_pushbutton.Text = {'Pick max. X'; ''};
            app.maxXLabel = uilabel(app.UIFigure);
            app.maxXLabel.Text = 'max. X';
            app.Max_X_edit = uieditfield(app.UIFigure, 'numeric');
            app.Max_X_edit.Value = 10;
            app.minXLabel = uilabel(app.UIFigure);
            app.minXLabel.Text = 'min. X';
            app.Min_X_edit = uieditfield(app.UIFigure, 'numeric');
            app.scaleofXDropDownLabel = uilabel(app.UIFigure);
            app.scaleofXDropDownLabel.Text = 'scale of X';
            app.ScaleofX_dropdown = uidropdown(app.UIFigure);
            app.ScaleofX_dropdown.Items = {'linear', 'log10'};
            app.ScaleofX_dropdown.Value = 'linear';
            app.scaleofYDropDownLabel = uilabel(app.UIFigure);
            app.scaleofYDropDownLabel.Text = 'scale of Y';
            app.ScaleofY_dropdown = uidropdown(app.UIFigure);
            app.ScaleofY_dropdown.Items = {'linear', 'log10'};
            app.ScaleofY_dropdown.Value = 'linear';
            app.DigitizeSeries_pushbutton = uibutton(app.UIFigure, 'push');
            app.DigitizeSeries_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @DigitizeSeries_pushbuttonButtonPushed, true);
            app.DigitizeSeries_pushbutton.Text = 'Digitize data';
            app.datatagEditFieldLabel = uilabel(app.UIFigure);
            app.datatagEditFieldLabel.Text = 'data tag';
            app.SeriesTag_edit = uieditfield(app.UIFigure, 'numeric');
            app.SaveSeries_pushbutton = uibutton(app.UIFigure, 'push');
            app.SaveSeries_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @SaveSeries_pushbuttonButtonPushed, true);
            app.SaveSeries_pushbutton.Text = ' Save data';
            app.PlotSeries_pushbutton = uibutton(app.UIFigure, 'push');
            app.PlotSeries_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @PlotSeries_pushbuttonButtonPushed, true);
            app.PlotSeries_pushbutton.Text = 'Plot data';
            app.pointsizeLabel = uilabel(app.UIFigure);
            app.pointsizeLabel.Text = 'point size';
            app.PlotPntSize_edit = uieditfield(app.UIFigure, 'numeric');
            app.PlotPntSize_edit.Value = 14;
            app.gridon_checkbox = uicheckbox(app.UIFigure);
            app.gridon_checkbox.Text = 'grid on';
            app.gridon_checkbox.Value = true;
            app.axisequal_checkbox = uicheckbox(app.UIFigure);
            app.axisequal_checkbox.Text = 'axis equal';
            app.axisequal_checkbox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = DigitizeFigure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end